classdef K04_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PacemakerControllerUIFigure    matlab.ui.Figure
        create_User                    matlab.ui.container.Panel
        BackButton                     matlab.ui.control.Button
        CreateUserButton               matlab.ui.control.Button
        newUsername                    matlab.ui.control.EditField
        UsernameEditField_2Label_2     matlab.ui.control.Label
        newPassword                    matlab.ui.control.EditField
        PasswordEditFieldLabel         matlab.ui.control.Label
        confirmNewPassword             matlab.ui.control.EditField
        ConfirmPasswordEditFieldLabel  matlab.ui.control.Label
        createUserTitle                matlab.ui.control.Label
        login                          matlab.ui.container.Panel
        loginTitle                     matlab.ui.control.Label
        UsernameEditField_2Label       matlab.ui.control.Label
        username                       matlab.ui.control.EditField
        PasswordEditField_2Label       matlab.ui.control.Label
        password                       matlab.ui.control.EditField
        NewUserButton                  matlab.ui.control.Button
        LoginButton                    matlab.ui.control.Button
        control_Panel                  matlab.ui.container.Panel
        logout                         matlab.ui.control.Button
        PacingStateLabel               matlab.ui.control.Label
        PacingModeLabel                matlab.ui.control.Label
        HysteresisLabel                matlab.ui.control.Label
        HysteresisIntervalLabel        matlab.ui.control.Label
        LowrateIntervalLabel           matlab.ui.control.Label
        VentricularPaceAmplitudeLabel  matlab.ui.control.Label
        VentricularPaceWidthLabel      matlab.ui.control.Label
        VentricularRefractoryPeriodLabel  matlab.ui.control.Label
        pacingState                    matlab.ui.control.EditField
        pacingModes                    matlab.ui.control.EditField
        hysteresisInterval             matlab.ui.control.EditField
        lowrateInterval                matlab.ui.control.EditField
        vpa                            matlab.ui.control.EditField
        vpw                            matlab.ui.control.EditField
        vrp                            matlab.ui.control.EditField
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ActivatedButton                matlab.ui.control.RadioButton
        DisactivatedButton             matlab.ui.control.RadioButton
        UpdateValuesButton             matlab.ui.control.Button
        controlPanelTitle              matlab.ui.control.Label
        mSecLabel                      matlab.ui.control.Label
        mSecLabel_2                    matlab.ui.control.Label
        mSecLabel_3                    matlab.ui.control.Label
        mSecLabel_4                    matlab.ui.control.Label
        mVLabel                        matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            filename = strcat(userpath,'\userandPass.MAT');
            
            if(exist(filename,'file'))
               m = load(filename);
               users = m.users;
               passwords = m.passwords;
               loggedIn = false;
               for i = 1:length(users)
                    if(strcmp(users(i),app.username.Value))
                        if((strcmp(passwords(i),app.password.Value)))
                            loggedIn = true;
                            app.username.Value = '';
                            app.password.Value = '';
                            paramsFile = strcat(userpath,'\params.MAT');
                            if(exist(paramsFile,'file'))
                                p = load(paramsFile);
                                app.pacingState.Value = p.pacingState;
                                app.pacingModes.Value = p.pacingModes;
                                app.hysteresisInterval.Value = p.hysteresisInterval;
                                app.lowrateInterval.Value = p.lowrateInterval;
                                app.vpa.Value = p.vpa;
                                app.vpw.Value = p.vpw;
                                app.vrp.Value = p.vrp;
                                if(strcmp(p.hysteresis,'Activated'))
                                    app.ButtonGroup.SelectedObject = app.ActivatedButton;
                                else
                                    app.ButtonGroup.SelectedObject = app.DisactivatedButton;
                                end
                            else
                                app.pacingState.Value = 'Permanent';
                                app.pacingModes.Value = 'VVI';
                                app.hysteresisInterval.Value = '300';
                                app.lowrateInterval.Value = '1000';
                                app.vpa.Value = '3500';
                                app.vpw.Value = '0.4';
                                app.vrp.Value = '320';
                                app.ButtonGroup.SelectedObject = app.DisactivatedButton;
                            end
                            app.login.Visible = 'off';
                            app.control_Panel.Visible = 'on';
                        end
                    end
               end
               if(~loggedIn)
                   msgbox('Username or password incorrect.')
                   app.username.Value = '';
                   app.password.Value = '';
                   
               end
            else
                msgbox('Username or password incorrect.')
                app.username.Value = '';
                app.password.Value = '';
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.create_User.Visible ='off'; 
            app.login.Visible = 'on';
            app.newUsername.Value = '';
            app.newPassword.Value = '';
            app.confirmNewPassword.Value = '';
        end

        % Button pushed function: NewUserButton
        function NewUserButtonPushed(app, event)
            app.create_User.Visible ='on'; 
            app.login.Visible = 'off';
            app.username.Value = '';
            app.password.Value = '';
        end

        % Button pushed function: CreateUserButton
        function CreateUserButtonPushed(app, event)
            app.newUsername.Value;
            passEqual = strcmp(app.newPassword.Value,app.confirmNewPassword.Value);
            
            if (~passEqual)
                msgbox('Passwords do not match. Please re-enter your password.')
                app.newUsername.Value = '';
                app.newPassword.Value = '';
                app.confirmNewPassword.Value = '';
            else if(isempty(app.newPassword.Value) || isempty(app.newUsername.Value))
                msgbox('Password and username fields must be filled.')
                app.newUsername.Value = '';
                app.newPassword.Value = '';
                app.confirmNewPassword.Value = '';
            else
                filename = strcat(userpath,'\userandPass.MAT');
                
                if(exist(filename,'file'))
                    m = load(filename);
                    users = m.users;
                    passwords = m.passwords;
                    unique = true;
                    
                    for i = 1:length(users)
                        if(strcmp(users(i),app.newUsername.Value))
                            msgbox('Username is not unique. Please pick a new username.')
                            app.newUsername.Value = '';
                            app.newPassword.Value = '';
                            app.confirmNewPassword.Value = '';
                            unique = false;
                        end
                    end
                    
                    if(unique)
                        if(length(users) < 10)
                           users{length(users) + 1} = app.newUsername.Value;
                           passwords{length(passwords) + 1} = app.newPassword.Value;
                           save(filename, 'users', 'passwords');
                           app.newUsername.Value = '';
                           app.newPassword.Value = '';
                           app.confirmNewPassword.Value = '';
                           app.create_User.Visible ='off'; 
                           app.login.Visible = 'on';
                        else
                            msgbox('Maximum number of users registered.')
                            app.newUsername.Value = '';
                            app.newPassword.Value = '';
                            app.confirmNewPassword.Value = '';
                        end
                    end
                else
                    users = {app.newUsername.Value};
                    passwords = {app.newPassword.Value};
                    save(filename, 'users', 'passwords');
                    app.newUsername.Value = '';
                    app.newPassword.Value = '';
                    app.confirmNewPassword.Value = '';
                    app.create_User.Visible ='off'; 
                    app.login.Visible = 'on';
                end 
            end
            end
        end

        % Button pushed function: logout
        function logoutButtonPushed(app, event)
            app.login.Visible = 'on';
            app.control_Panel.Visible = 'off';
        end

        % Button pushed function: UpdateValuesButton
        function UpdateValuesButtonPushed(app, event)
            filename = strcat(userpath,'\params.MAT');
            pacingState = app.pacingState.Value;
            pacingModes = app.pacingModes.Value;
            hysteresisInterval = app.hysteresisInterval.Value;
            lowrateInterval = app.lowrateInterval.Value;
            vpa = app.vpa.Value;
            vpw = app.vpw.Value;
            vrp = app.vrp.Value;
            hysteresis = app.ButtonGroup.SelectedObject.Text;
            save(filename, 'pacingState', 'pacingModes', 'hysteresisInterval', 'lowrateInterval', 'vpa', 'vpw', 'vrp', 'hysteresis');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PacemakerControllerUIFigure = uifigure;
            app.PacemakerControllerUIFigure.Name = 'Pacemaker Controller';
            app.create_User = uipanel(app.PacemakerControllerUIFigure);
            app.create_User.Visible = 'off';
            app.BackButton = uibutton(app.create_User, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.CreateUserButton = uibutton(app.create_User, 'push');
            app.CreateUserButton.ButtonPushedFcn = createCallbackFcn(app, @CreateUserButtonPushed, true);
            app.CreateUserButton.Text = 'Create User';
            app.newUsername = uieditfield(app.create_User, 'text');
            app.UsernameEditField_2Label_2 = uilabel(app.create_User);
            app.UsernameEditField_2Label_2.Text = 'Username';
            app.newPassword = uieditfield(app.create_User, 'text');
            app.PasswordEditFieldLabel = uilabel(app.create_User);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.confirmNewPassword = uieditfield(app.create_User, 'text');
            app.ConfirmPasswordEditFieldLabel = uilabel(app.create_User);
            app.ConfirmPasswordEditFieldLabel.Text = 'Confirm Password';
            app.createUserTitle = uilabel(app.create_User);
            app.createUserTitle.Text = 'PACEMAKER';
            app.login = uipanel(app.PacemakerControllerUIFigure);
            app.loginTitle = uilabel(app.login);
            app.loginTitle.Text = 'PACEMAKER';
            app.UsernameEditField_2Label = uilabel(app.login);
            app.UsernameEditField_2Label.Text = 'Username';
            app.username = uieditfield(app.login, 'text');
            app.PasswordEditField_2Label = uilabel(app.login);
            app.PasswordEditField_2Label.Text = 'Password';
            app.password = uieditfield(app.login, 'text');
            app.NewUserButton = uibutton(app.login, 'push');
            app.NewUserButton.ButtonPushedFcn = createCallbackFcn(app, @NewUserButtonPushed, true);
            app.NewUserButton.Text = 'New User';
            app.LoginButton = uibutton(app.login, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.control_Panel = uipanel(app.PacemakerControllerUIFigure);
            app.control_Panel.Visible = 'off';
            app.logout = uibutton(app.control_Panel, 'push');
            app.logout.ButtonPushedFcn = createCallbackFcn(app, @logoutButtonPushed, true);
            app.logout.Text = 'Logout';
            app.PacingStateLabel = uilabel(app.control_Panel);
            app.PacingStateLabel.Text = 'Pacing State:';
            app.PacingModeLabel = uilabel(app.control_Panel);
            app.PacingModeLabel.Text = 'Pacing Mode:';
            app.HysteresisLabel = uilabel(app.control_Panel);
            app.HysteresisLabel.Text = 'Hysteresis:';
            app.HysteresisIntervalLabel = uilabel(app.control_Panel);
            app.HysteresisIntervalLabel.Text = 'Hysteresis Interval:';
            app.LowrateIntervalLabel = uilabel(app.control_Panel);
            app.LowrateIntervalLabel.Text = 'Lowrate Interval:';
            app.VentricularPaceAmplitudeLabel = uilabel(app.control_Panel);
            app.VentricularPaceAmplitudeLabel.Text = 'Ventricular Pace Amplitude:';
            app.VentricularPaceWidthLabel = uilabel(app.control_Panel);
            app.VentricularPaceWidthLabel.Text = 'Ventricular Pace Width:';
            app.VentricularRefractoryPeriodLabel = uilabel(app.control_Panel);
            app.VentricularRefractoryPeriodLabel.Text = 'Ventricular Refractory Period:';
            app.pacingState = uieditfield(app.control_Panel, 'text');
            app.pacingModes = uieditfield(app.control_Panel, 'text');
            app.hysteresisInterval = uieditfield(app.control_Panel, 'text');
            app.lowrateInterval = uieditfield(app.control_Panel, 'text');
            app.vpa = uieditfield(app.control_Panel, 'text');
            app.vpw = uieditfield(app.control_Panel, 'text');
            app.vrp = uieditfield(app.control_Panel, 'text');
            app.ButtonGroup = uibuttongroup(app.control_Panel);
            app.ActivatedButton = uiradiobutton(app.ButtonGroup);
            app.ActivatedButton.Text = 'Activated';
            app.ActivatedButton.Value = true;
            app.DisactivatedButton = uiradiobutton(app.ButtonGroup);
            app.DisactivatedButton.Text = 'Disactivated';
            app.UpdateValuesButton = uibutton(app.control_Panel, 'push');
            app.UpdateValuesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateValuesButtonPushed, true);
            app.UpdateValuesButton.Text = 'Update Values';
            app.controlPanelTitle = uilabel(app.control_Panel);
            app.controlPanelTitle.Text = 'CONTROL PANEL';
            app.mSecLabel = uilabel(app.control_Panel);
            app.mSecLabel.Text = 'mSec.';
            app.mSecLabel_2 = uilabel(app.control_Panel);
            app.mSecLabel_2.Text = 'mSec.';
            app.mSecLabel_3 = uilabel(app.control_Panel);
            app.mSecLabel_3.Text = 'mSec.';
            app.mSecLabel_4 = uilabel(app.control_Panel);
            app.mSecLabel_4.Text = 'mSec.';
            app.mVLabel = uilabel(app.control_Panel);
            app.mVLabel.Text = 'mV';
        end
    end

    methods (Access = public)

        % Construct app
        function app = K04_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PacemakerControllerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PacemakerControllerUIFigure)
        end
    end
end