classdef Assingment2_3K04_DCM_Group_7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PacemakerControllerUIFigure    matlab.ui.Figure
        create_User                    matlab.ui.container.Panel
        BackButton                     matlab.ui.control.Button
        CreateUserButton               matlab.ui.control.Button
        newUsername                    matlab.ui.control.EditField
        UsernameEditField_2Label_2     matlab.ui.control.Label
        newPassword                    matlab.ui.control.EditField
        PasswordEditFieldLabel         matlab.ui.control.Label
        confirmNewPassword             matlab.ui.control.EditField
        ConfirmPasswordEditFieldLabel  matlab.ui.control.Label
        createUserTitle                matlab.ui.control.Label
        login                          matlab.ui.container.Panel
        loginTitle                     matlab.ui.control.Label
        UsernameEditField_2Label       matlab.ui.control.Label
        username                       matlab.ui.control.EditField
        PasswordEditField_2Label       matlab.ui.control.Label
        password                       matlab.ui.control.EditField
        NewUserButton                  matlab.ui.control.Button
        LoginButton                    matlab.ui.control.Button
        control_Panel                  matlab.ui.container.Panel
        logout                         matlab.ui.control.Button
        AvDelayLabel                   matlab.ui.control.Label
        PacingModeLabel                matlab.ui.control.Label
        UpperRateLimitLabel            matlab.ui.control.Label
        LowRateLimitLabel              matlab.ui.control.Label
        VentricularPaceAmplitudeLabel  matlab.ui.control.Label
        VentricularPaceWidthLabel      matlab.ui.control.Label
        PaceWidthATRLabel              matlab.ui.control.Label
        pacingModes                    matlab.ui.control.EditField
        avDelay                        matlab.ui.control.EditField
        lowrateLimit                   matlab.ui.control.EditField
        VTRPaceAmplitude               matlab.ui.control.EditField
        VentricularPaceWidth           matlab.ui.control.EditField
        paceWidthATR                   matlab.ui.control.EditField
        controlPanelTitle              matlab.ui.control.Label
        BPMLabel                       matlab.ui.control.Label
        BPMLabel_2                     matlab.ui.control.Label
        mSecLabel_3                    matlab.ui.control.Label
        mSecLabel_4                    matlab.ui.control.Label
        mVLabel                        matlab.ui.control.Label
        ShowegramButton                matlab.ui.control.Button
        SaveTransmitButton             matlab.ui.control.Button
        ATRPaceAmplitudeLabel          matlab.ui.control.Label
        ATRPaceAmplitude               matlab.ui.control.EditField
        upperRateLimit                 matlab.ui.control.EditField
        DropDown                       matlab.ui.control.DropDown
        EgramSettingLabel              matlab.ui.control.Label
        EgramSettingDropDown           matlab.ui.control.DropDown
        RefforVentricalLabel           matlab.ui.control.Label
        RefforATRLabel                 matlab.ui.control.Label
        refVent                        matlab.ui.control.EditField
        refAtr                         matlab.ui.control.EditField
        mSecLabel_5                    matlab.ui.control.Label
        mVLabel_2                      matlab.ui.control.Label
        mSecLabel_6                    matlab.ui.control.Label
        mSecLabel_7                    matlab.ui.control.Label
        egram                          matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        BackButton_2                   matlab.ui.control.Button
    end


    methods (Access = private)

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            filename = strcat(userpath,'\userandPass.MAT');
            
            if(exist(filename,'file'))
               m = load(filename);
               users = m.users;
               passwords = m.passwords;
               loggedIn = false;
               for i = 1:length(users)
                    if(strcmp(users(i),app.username.Value))
                        if((strcmp(passwords(i),app.password.Value)))
                            loggedIn = true;
                            app.username.Value = '';
                            app.password.Value = '';
                            paramsFile = strcat(userpath,'\params.MAT');
                            if(exist(paramsFile,'file'))
                                p = load(paramsFile);
                                app.DropDown.Value = num2str(p.pacingMode);
                                app.avDelay.Value = num2str(p.AvDelay);
                                app.upperRateLimit.Value = num2str(p.uprL);
                                app.lowrateLimit.Value = num2str(p.lwrL);
                                app.VTRPaceAmplitude.Value = num2str(p.VTRAmpl);
                                app.ATRPaceAmplitude.Value = num2str(p.ATRAmpl);
                                app.VentricularPaceWidth.Value = num2str(p.pwVTR);
                                app.refVent.Value = num2str(p.refVTR);
                                app.refAtr.Value = num2str(p.refATR);
                                app.paceWidthATR.Value = num2str(p.pwATR);
                            else
                                app.avDelay.Value = '250';
                                app.upperRateLimit.Value = '120';
                                app.lowrateLimit.Value = '60';
                                app.VTRPaceAmplitude.Value = '4500';
                                app.ATRPaceAmplitude.Value = '4500';
                                app.VentricularPaceWidth.Value = '2';
                                app.refVent.Value = '400';
                                app.refAtr.Value = '400';
                                app.paceWidthATR.Value = '2';
                                
                            end
                            app.login.Visible = 'off';
                            app.control_Panel.Visible = 'on';
                        end
                    end
               end
               if(~loggedIn)
                   msgbox('Username or password incorrect.')
                   app.username.Value = '';
                   app.password.Value = '';
                   
               end
            else
                msgbox('Username or password incorrect.')
                app.username.Value = '';
                app.password.Value = '';
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.create_User.Visible ='off'; 
            app.login.Visible = 'on';
            app.newUsername.Value = '';
            app.newPassword.Value = '';
            app.confirmNewPassword.Value = '';
        end

        % Button pushed function: NewUserButton
        function NewUserButtonPushed(app, event)
            app.create_User.Visible ='on'; 
            app.login.Visible = 'off';
            app.username.Value = '';
            app.password.Value = '';
        end

        % Button pushed function: CreateUserButton
        function CreateUserButtonPushed(app, event)
            app.newUsername.Value;
            passEqual = strcmp(app.newPassword.Value,app.confirmNewPassword.Value);
            
            if (~passEqual)
                msgbox('Passwords do not match. Please re-enter your password.')
                app.newUsername.Value = '';
                app.newPassword.Value = '';
                app.confirmNewPassword.Value = '';
            else if(isempty(app.newPassword.Value) || isempty(app.newUsername.Value))
                msgbox('Password and username fields must be filled.')
                app.newUsername.Value = '';
                app.newPassword.Value = '';
                app.confirmNewPassword.Value = '';
            else
                filename = strcat(userpath,'\userandPass.MAT');
                
                if(exist(filename,'file'))
                    m = load(filename);
                    users = m.users;
                    passwords = m.passwords;
                    unique = true;
                    
                    for i = 1:length(users)
                        if(strcmp(users(i),app.newUsername.Value))
                            msgbox('Username is not unique. Please pick a new username.')
                            app.newUsername.Value = '';
                            app.newPassword.Value = '';
                            app.confirmNewPassword.Value = '';
                            unique = false;
                        end
                    end
                    
                    if(unique)
                        if(length(users) < 10)
                           users{length(users) + 1} = app.newUsername.Value;
                           passwords{length(passwords) + 1} = app.newPassword.Value;
                           save(filename, 'users', 'passwords');
                           app.newUsername.Value = '';
                           app.newPassword.Value = '';
                           app.confirmNewPassword.Value = '';
                           app.create_User.Visible ='off'; 
                           app.login.Visible = 'on';
                        else
                            msgbox('Maximum number of users registered.')
                            app.newUsername.Value = '';
                            app.newPassword.Value = '';
                            app.confirmNewPassword.Value = '';
                        end
                    end
                else
                    users = {app.newUsername.Value};
                    passwords = {app.newPassword.Value};
                    save(filename, 'users', 'passwords');
                    app.newUsername.Value = '';
                    app.newPassword.Value = '';
                    app.confirmNewPassword.Value = '';
                    app.create_User.Visible ='off'; 
                    app.login.Visible = 'on';
                end 
            end
            end
        end

        % Button pushed function: logout
        function logoutButtonPushed(app, event)
            app.login.Visible = 'on';
            app.control_Panel.Visible = 'off';
        end

        % Button pushed function: ShowegramButton
        function ShowegramButtonPushed(app, event)
            paramsFile = strcat(userpath,'\params.MAT');
            p = load(paramsFile);
            pacingMode = p.pacingMode;
            AvDelay = p.AvDelay;
            uprL = p.uprL;
            lwrL = p.lwrL;
            VTRAmpl = p.VTRAmpl;
            ATRAmpl = p.ATRAmpl;
            pwVTR = p.pwVTR;
            pwATR = p.pwATR;
            refVTR = p.refVTR;
            refATR = p.refATR;
            eGramConst = p.eGramConst;
                       
             if (strcmp(pacingMode, 'IDLE'))
                pace = 0;
            elseif (strcmp(pacingMode, 'VOO'))
                pace = 1;
            elseif (strcmp(pacingMode, 'AOO'))
                pace = 2;
            elseif (strcmp(pacingMode, 'VVI'))
                pace = 3;
            elseif (strcmp(pacingMode, 'AAI'))
                pace = 4;
            elseif (strcmp(pacingMode, 'VOOR'))
                pace = 5;
            elseif (strcmp(pacingMode, 'AOOR'))
                pace = 6;
            elseif (strcmp(pacingMode, 'VVIR'))
                pace = 7;
            elseif (strcmp(pacingMode, 'AAIR'))
                pace = 8;
            elseif (strcmp(pacingMode, 'DOO'))
                pace = 9;
            elseif (strcmp(pacingMode, 'DOOR'))
                pace = 10;
             end
            
            try
                delete(instrfind);
                s = serial('COM4','BaudRate',115200, 'DataBits', 8);
                fopen(s);
                
                A = [pace, uprL, lwrL, (AvDelay/10), (VTRAmpl/100), (ATRAmpl/100), pwVTR, pwATR, (refVTR/10), (refATR/10), eGramConst];
                B = uint8(A);
                fwrite(s,B);
            catch e
                fprintf(1,'The identifier was:\n%s',e.identifier);
                fprintf(1,'There was an error! The message was:\n%s',e.message);
            end

            fclose(s);
            delete(s);
            
            app.egram.Visible = 'on';
            app.control_Panel.Visible = 'off';
                        
%             s = serial('COM4');
%             set(s,'BaudRate',115200);
%             fopen(s);
%             try
%                 s.Timeout = 5;
%                 
%                 sampsPerWindow = 500; %Number of samples displayed on the graph at a time.
%                 
%                 stream = zeros(sampsPerWindow, 1);
%                 vals = zeros(sampsPerWindow, 1);
%                 j = 1;
%                 i = 1;
%                 ploti = 1;
%                 
%                 %FigHandle = figure;
%                 %set(FigHandle, 'Position', [300, 150, 600, 480]);
%                 h = animatedline('Color','r','marker','.', 'markersize', 3); %REQUIRES R2014b OR LATER
%             
%                 axis([0,sampsPerWindow,0,5]);
%                 drawnow;
%                 s1 = ['Line Plot of ', num2str(sampsPerWindow) ,' Samples'];
%                 title(s1);
%                 xlabel('Samples');
%                 ylabel('Voltage (V)');
%  
%                 while 1
%                     % gather the data and plot in "real-time"
%                     stream(ploti,1) = fread(s,1); 
%                     
%                     %if((mod(ploti, 2) == 0) && (ploti > 2))
%                     vals(j,1) = uint8(stream(ploti-1,1));
%                     %temp = uint16(stream(ploti,1));
%                     %append  = bitshift(temp, 8);
%                     %vals(j,1) = vals(j,1) + append;
%                     disp(vals(j,1));
%                     
%                     ix = rem(i,sampsPerWindow) + 1;
%                     y(ix) = ((vals(j,1))/1023)*5; % <- new data
%                     addpoints(h,ix,y(ix));
%                         
%                     if(mod(ploti, 30) == 0) %only update every 30 data points
%                         drawnow;
%                     end
%                     j = j + 1;
%                     i = i + 1;
%                     end
%                     ploti = ploti + 1;
%                     
%                     if (i == sampsPerWindow) %Clear window when it is full
%                         delete(h);
%                         h = animatedline('Color','r', 'marker','.', 'markersize', 3); %REQUIRES R2014b OR LATER
%                         drawnow;
%                         stream = zeros(sampsPerWindow, 1);
%                         vals = zeros(sampsPerWindow, 1);
%                         i = 2;
%                         j = 1;
%                     end
%                     
%             catch e
%                 close all; %Return to Idle.m
%                 s.Timeout = 2;
%             end
            s = serial('COM4');
            set(s,'BaudRate',115200);
            fopen(s);
            A = fread(s);
            s.Timeout = 3;
            
            try
                data = zeros(1,151); %with a smaller number, the graph will update faster. number of y points per sample
                time = zeros(151); %with a smaller number, the graph will update faster. number of x points per package
                
                for i=1:inf
                    for j=1:151
                        data(1,j+1) = (fread(s,1,'uchar')); %steps multiplied by voltage per step. gives me a ratio*my received voltage
                        time(1,j+1) = j*(0.001); %multiples of my sampling period
                    end
                    axes(app.UIAxes);
                    app.UIAxes.plot(data);
                    app.UIAxes.axis([1,151, 0,5]); 
                    app.UIAxes.title('Voltage Output vs Time Graph')
                    app.UIAxes.ylabel('Voltage(V)')
                    app.UIAxes.xlabel('Time(s)')
                    app.UIAxes.drawnow;
                    stop_state = get(BackButton_2Pushed, 'Value');
                    if (stop_state) 
                        break;
                    end
                end
            catch
                fclose(s);
                delete(s);
            end

        end

        % Callback function
        function ControlPanelButtonPushed(app, event)
            app.control_Panel.Visible = 'on';
            app.egram.Visible = 'off';
        end

        % Callback function
        function logout_2ButtonPushed(app, event)
            app.login.Visible ='on';
            app.egram.Visible = 'off';
            app.control_Panel.Visible = 'off';
        end

        % Button pushed function: SaveTransmitButton
        function SaveTransmitButtonPushed(app, event)
            filename = strcat(userpath,'\params.MAT');
            
            pacingMode = app.DropDown.Value;
            AvDelay = str2num(app.avDelay.Value);
            uprL = str2num(app.upperRateLimit.Value);
            lwrL = str2num(app.lowrateLimit.Value);
            VTRAmpl = str2num(app.VTRPaceAmplitude.Value);
            ATRAmpl = str2num(app.ATRPaceAmplitude.Value);
            pwVTR = str2num(app.VentricularPaceWidth.Value);
            pwATR = str2num(app.paceWidthATR.Value);
            refVTR = str2num(app.refVent.Value);
            refATR = str2num(app.refAtr.Value);
            eGramConst = str2num(app.EgramSettingDropDown.Value);
            
            if ((AvDelay < 70) || (AvDelay > 300))
                msgbox('AV Delay parameter out of acceptable range. Please enter a value between 70 and 300.')
            elseif ((uprL < 60) || (uprL > 175))
                msgbox('Upper Rate Limit parameter out of acceptable range. Please enter a value between 30 and 175.')
            elseif ((lwrL < 60) || (lwrL > 175))
                msgbox('Lower Rate Limit parameter out of acceptable range. Please enter a value between 30 and 175.')
            elseif ((VTRAmpl < 500) || (VTRAmpl > 5000))
                msgbox('Ventricular Pace Amplitude parameter out of acceptable range. Please enter a value between 500 and 5000.')
            elseif ((ATRAmpl < 500) || (ATRAmpl > 5000))
                msgbox('Atrium Pace Amplitude parameter out of acceptable range. Please enter a value between 500 and 5000.')
            elseif ((pwVTR <= 0) || (pwVTR > 50))
                msgbox('Ventricular Pace Width parameter out of acceptable range. Please enter a value between 0 and 2.')
            elseif ((pwATR <= 0) || (pwATR > 50))
                msgbox('Atrium Pace Width parameter out of acceptable range. Please enter a value between 0 and 2.')
            elseif ((refVTR < 150) || (refVTR > 500))
                msgbox('Ventricular Reference parameter out of acceptable range. Please enter a value between 150 and 500.')
            elseif ((refATR < 150) || (refATR > 500))
                msgbox('Atrium Reference parameter out of acceptable range. Please enter a value between 150 and 500.')
            else   
                save(filename, 'pacingMode', 'AvDelay', 'uprL', 'lwrL', 'VTRAmpl', 'ATRAmpl', 'pwVTR', 'pwATR', 'refVTR', 'refATR', 'eGramConst');
            end
            
            if (strcmp(pacingMode, 'IDLE'))
                pace = 0;
            elseif (strcmp(pacingMode, 'VOO'))
                pace = 1;
            elseif (strcmp(pacingMode, 'AOO'))
                pace = 2;
            elseif (strcmp(pacingMode, 'VVI'))
                pace = 3;
            elseif (strcmp(pacingMode, 'AAI'))
                pace = 4;
            elseif (strcmp(pacingMode, 'VOOR'))
                pace = 5;
            elseif (strcmp(pacingMode, 'AOOR'))
                pace = 6;
            elseif (strcmp(pacingMode, 'VVIR'))
                pace = 7;
            elseif (strcmp(pacingMode, 'AAIR'))
                pace = 8;
            elseif (strcmp(pacingMode, 'DOO'))
                pace = 9;
            elseif (strcmp(pacingMode, 'DOOR'))
                pace = 10;
            end
            
            try
                delete(instrfind);
                s = serial('COM4','BaudRate',115200, 'DataBits', 8);
                fopen(s);
                
                A = [pace, uprL, lwrL, (AvDelay/10), (VTRAmpl/100), (ATRAmpl/100), pwVTR, pwATR, (refVTR/10), (refATR/10), 0];
                B = uint8(A);
                fwrite(s,B);
            catch e
                fprintf(1,'The identifier was:\n%s',e.identifier);
                fprintf(1,'There was an error! The message was:\n%s',e.message);
                fclose(s);
                delete(s);
            end
            %{

            
            fwrite(s, uint8(uprL))
            fwrite(s, uint8(lwrL))
     
            fwrite(s, uint8(floor(AvDelay/10)))
            
            fwrite(s, uint8(floor(VTRAmpl/100)))
            fwrite(s, uint8(floor(ATRAmpl/100)))
           
            fwrite(s, uint8(pwVTR))
            fwrite(s, uint8(pwATR))

            fwrite(s, uint8(floor(refVTR/10)))
            fwrite(s, uint8(floor(refATR/10)))
            
            fwrite (s, uint8(0))
               %}
            %end serial communication
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.egram.Visible = 'off';
            app.control_Panel.Visible = 'on';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PacemakerControllerUIFigure = uifigure;
            app.PacemakerControllerUIFigure.Name = 'Pacemaker Controller';
            app.create_User = uipanel(app.PacemakerControllerUIFigure);
            app.create_User.Visible = 'off';
            app.BackButton = uibutton(app.create_User, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.CreateUserButton = uibutton(app.create_User, 'push');
            app.CreateUserButton.ButtonPushedFcn = createCallbackFcn(app, @CreateUserButtonPushed, true);
            app.CreateUserButton.Text = 'Create User';
            app.newUsername = uieditfield(app.create_User, 'text');
            app.UsernameEditField_2Label_2 = uilabel(app.create_User);
            app.UsernameEditField_2Label_2.Text = 'Username';
            app.newPassword = uieditfield(app.create_User, 'text');
            app.PasswordEditFieldLabel = uilabel(app.create_User);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.confirmNewPassword = uieditfield(app.create_User, 'text');
            app.ConfirmPasswordEditFieldLabel = uilabel(app.create_User);
            app.ConfirmPasswordEditFieldLabel.Text = 'Confirm Password';
            app.createUserTitle = uilabel(app.create_User);
            app.createUserTitle.Text = 'PACEMAKER';
            app.login = uipanel(app.PacemakerControllerUIFigure);
            app.loginTitle = uilabel(app.login);
            app.loginTitle.Text = 'PACEMAKER';
            app.UsernameEditField_2Label = uilabel(app.login);
            app.UsernameEditField_2Label.Text = 'Username';
            app.username = uieditfield(app.login, 'text');
            app.PasswordEditField_2Label = uilabel(app.login);
            app.PasswordEditField_2Label.Text = 'Password';
            app.password = uieditfield(app.login, 'text');
            app.NewUserButton = uibutton(app.login, 'push');
            app.NewUserButton.ButtonPushedFcn = createCallbackFcn(app, @NewUserButtonPushed, true);
            app.NewUserButton.Text = 'New User';
            app.LoginButton = uibutton(app.login, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.control_Panel = uipanel(app.PacemakerControllerUIFigure);
            app.control_Panel.Visible = 'off';
            app.logout = uibutton(app.control_Panel, 'push');
            app.logout.ButtonPushedFcn = createCallbackFcn(app, @logoutButtonPushed, true);
            app.logout.Text = 'Logout';
            app.AvDelayLabel = uilabel(app.control_Panel);
            app.AvDelayLabel.Text = 'Av Delay:';
            app.PacingModeLabel = uilabel(app.control_Panel);
            app.PacingModeLabel.Text = 'Pacing Mode:';
            app.UpperRateLimitLabel = uilabel(app.control_Panel);
            app.UpperRateLimitLabel.Text = 'Upper Rate Limit:';
            app.LowRateLimitLabel = uilabel(app.control_Panel);
            app.LowRateLimitLabel.Text = 'Low Rate Limit:';
            app.VentricularPaceAmplitudeLabel = uilabel(app.control_Panel);
            app.VentricularPaceAmplitudeLabel.Text = 'Ventricular Pace Amplitude:';
            app.VentricularPaceWidthLabel = uilabel(app.control_Panel);
            app.VentricularPaceWidthLabel.Text = 'Ventricular Pace Width:';
            app.PaceWidthATRLabel = uilabel(app.control_Panel);
            app.PaceWidthATRLabel.Text = 'Pace Width ATR:';
            app.pacingModes = uieditfield(app.control_Panel, 'text');
            app.avDelay = uieditfield(app.control_Panel, 'text');
            app.lowrateLimit = uieditfield(app.control_Panel, 'text');
            app.VTRPaceAmplitude = uieditfield(app.control_Panel, 'text');
            app.VentricularPaceWidth = uieditfield(app.control_Panel, 'text');
            app.paceWidthATR = uieditfield(app.control_Panel, 'text');
            app.controlPanelTitle = uilabel(app.control_Panel);
            app.controlPanelTitle.Text = 'CONTROL PANEL';
            app.BPMLabel = uilabel(app.control_Panel);
            app.BPMLabel.Text = 'BPM';
            app.BPMLabel_2 = uilabel(app.control_Panel);
            app.BPMLabel_2.Text = 'BPM';
            app.mSecLabel_3 = uilabel(app.control_Panel);
            app.mSecLabel_3.Text = 'mSec.';
            app.mSecLabel_4 = uilabel(app.control_Panel);
            app.mSecLabel_4.Text = 'mSec.';
            app.mVLabel = uilabel(app.control_Panel);
            app.mVLabel.Text = 'mV';
            app.ShowegramButton = uibutton(app.control_Panel, 'push');
            app.ShowegramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowegramButtonPushed, true);
            app.ShowegramButton.Text = 'Show egram';
            app.SaveTransmitButton = uibutton(app.control_Panel, 'push');
            app.SaveTransmitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTransmitButtonPushed, true);
            app.SaveTransmitButton.Text = 'Save & Transmit';
            app.ATRPaceAmplitudeLabel = uilabel(app.control_Panel);
            app.ATRPaceAmplitudeLabel.Text = 'ATR Pace Amplitude: ';
            app.ATRPaceAmplitude = uieditfield(app.control_Panel, 'text');
            app.upperRateLimit = uieditfield(app.control_Panel, 'text');
            app.DropDown = uidropdown(app.control_Panel);
            app.DropDown.Items = {'IDLE', 'VOO', 'AOO', 'VVI', 'AAI', 'VOOR', 'AOOR', 'VVIR', 'AAIR', 'DOO', 'DOOR'};
            app.DropDown.Value = 'IDLE';
            app.EgramSettingLabel = uilabel(app.control_Panel);
            app.EgramSettingLabel.Text = 'Egram Setting:';
            app.EgramSettingDropDown = uidropdown(app.control_Panel);
            app.EgramSettingDropDown.Items = {'Ventrical', 'Atrium'};
            app.EgramSettingDropDown.Value = 'Ventrical';
            app.RefforVentricalLabel = uilabel(app.control_Panel);
            app.RefforVentricalLabel.Text = 'Ref. for Ventrical: ';
            app.RefforATRLabel = uilabel(app.control_Panel);
            app.RefforATRLabel.Text = 'Ref. for ATR:';
            app.refVent = uieditfield(app.control_Panel, 'text');
            app.refAtr = uieditfield(app.control_Panel, 'text');
            app.mSecLabel_5 = uilabel(app.control_Panel);
            app.mSecLabel_5.Text = 'mSec.';
            app.mVLabel_2 = uilabel(app.control_Panel);
            app.mVLabel_2.Text = 'mV';
            app.mSecLabel_6 = uilabel(app.control_Panel);
            app.mSecLabel_6.Text = 'mSec.';
            app.mSecLabel_7 = uilabel(app.control_Panel);
            app.mSecLabel_7.Text = 'mSec.';
            app.egram = uipanel(app.PacemakerControllerUIFigure);
            app.egram.Visible = 'off';
            app.UIAxes = uiaxes(app.egram);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage')
            app.BackButton_2 = uibutton(app.egram, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Assingment2_3K04_DCM_Group_7

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PacemakerControllerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PacemakerControllerUIFigure)
        end
    end
end