classdef Assingment3_3K04_DCM_Group_7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PacemakerControllerUIFigure    matlab.ui.Figure
        create_User                    matlab.ui.container.Panel
        BackButton                     matlab.ui.control.Button
        CreateUserButton               matlab.ui.control.Button
        newUsername                    matlab.ui.control.EditField
        UsernameEditField_2Label_2     matlab.ui.control.Label
        newPassword                    matlab.ui.control.EditField
        PasswordEditFieldLabel         matlab.ui.control.Label
        confirmNewPassword             matlab.ui.control.EditField
        ConfirmPasswordEditFieldLabel  matlab.ui.control.Label
        createUserTitle                matlab.ui.control.Label
        login                          matlab.ui.container.Panel
        loginTitle                     matlab.ui.control.Label
        UsernameEditField_2Label       matlab.ui.control.Label
        username                       matlab.ui.control.EditField
        PasswordEditField_2Label       matlab.ui.control.Label
        password                       matlab.ui.control.EditField
        NewUserButton                  matlab.ui.control.Button
        LoginButton                    matlab.ui.control.Button
        control_Panel                  matlab.ui.container.Panel
        logout                         matlab.ui.control.Button
        controlPanelTitle              matlab.ui.control.Label
        SerialPanel                    matlab.ui.container.Panel
        PortsDropDownLabel             matlab.ui.control.Label
        PortList                       matlab.ui.control.DropDown
        ConnectButton                  matlab.ui.control.Button
        DisconnectButton               matlab.ui.control.Button
        SaveTransmitButton             matlab.ui.control.Button
        L1                             matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
        ParametersPanel                matlab.ui.container.Panel
        AvDelayLabel                   matlab.ui.control.Label
        PacingModeLabel                matlab.ui.control.Label
        UpperRateLimitLabel            matlab.ui.control.Label
        LowRateLimitLabel              matlab.ui.control.Label
        VentricularPaceAmplitudeLabel  matlab.ui.control.Label
        VentricularPaceWidthLabel      matlab.ui.control.Label
        PaceWidthATRLabel              matlab.ui.control.Label
        pacingModes                    matlab.ui.control.EditField
        avDelay                        matlab.ui.control.EditField
        lowrateLimit                   matlab.ui.control.EditField
        VTRPaceAmplitude               matlab.ui.control.EditField
        VentricularPaceWidth           matlab.ui.control.EditField
        paceWidthATR                   matlab.ui.control.EditField
        BPMLabel                       matlab.ui.control.Label
        BPMLabel_2                     matlab.ui.control.Label
        mSecLabel_3                    matlab.ui.control.Label
        mSecLabel_4                    matlab.ui.control.Label
        mVLabel                        matlab.ui.control.Label
        ATRPaceAmplitudeLabel          matlab.ui.control.Label
        ATRPaceAmplitude               matlab.ui.control.EditField
        upperRateLimit                 matlab.ui.control.EditField
        DropDown                       matlab.ui.control.DropDown
        RefPeriodforVentricleLabel     matlab.ui.control.Label
        RefPeriodforATRLabel           matlab.ui.control.Label
        refVent                        matlab.ui.control.EditField
        refAtr                         matlab.ui.control.EditField
        mSecLabel_5                    matlab.ui.control.Label
        mVLabel_2                      matlab.ui.control.Label
        mSecLabel_6                    matlab.ui.control.Label
        mSecLabel_7                    matlab.ui.control.Label
        ElectrogramSettingsPanel       matlab.ui.container.Panel
        VentricleCheckBox              matlab.ui.control.CheckBox
        AtriumCheckBox                 matlab.ui.control.CheckBox
        SampleTime                     matlab.ui.control.DropDown
        SampleTimeDropDownLabel        matlab.ui.control.Label
        NumSamplesEditFieldLabel       matlab.ui.control.Label
        NumSamplesEditField            matlab.ui.control.NumericEditField
        EnableElectorgramButton        matlab.ui.control.StateButton
    end


    properties (Access = private)
        s;
        com_open = false; %Flag to determine if com is open
        bufferSize = 2; %Size of serial buffer
        baud = 115200;
        GREEN_COLOR = [0 1 0]; % for lamps
        RED_COLOR = [1 0 0];
        sampleNum;
        t; %timer
        time;
        time_step = 0.1; %Increase this value to reduce lag on the system
        egram_array;
        atr_array;
        vent_array;
    end

    methods (Access = private)
        is
        function UpdateEgram(app)
            %Clear input buffer for new data
            flushinput(app.s);
            pause(0.001); % Wait for new data
            
            current_values = (fread(app.s, 2, 'uint8')/255.0)' .* 3.3; %Read Serial data
            
            % update serial values
            % (discard the 1-st element, append the new element)
            app.egram_array = [app.egram_array(2 : end, :); current_values];
            app.atr_array = [app.atr_array(2 : end, :); current_values(2)];
            app.vent_array = [app.vent_array(2 : end, :); current_values(1)];

            % plot Serial values in GUI
            if (app.VentricleCheckBox.Value == 1 && app.AtriumCheckBox.Value == 1) 
                plot(app.UIAxes, app.time, app.egram_array, 'LineWidth', 2);
                legend(app.UIAxes, 'Ventricle', 'Atrium');
            elseif ((app.VentricleCheckBox.Value == 1) && (app.AtriumCheckBox.Value == 0)) 
                plot(app.UIAxes, app.time, app.vent_array, 'LineWidth', 2);
                legend(app.UIAxes, 'Ventricle');
            elseif ((app.VentricleCheckBox.Value == 0) && (app.AtriumCheckBox.Value == 1))         
                plot(app.UIAxes, app.time, app.atr_array, 'LineWidth', 2);
                legend(app.UIAxes, 'Atrium');
            end
            
            app.UIAxes.XLim = [0 app.sampleNum];
            ylim(app.UIAxes, [-0.5 5]); % COnfirm Y axis limits
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sampleNum = 100;
            app.L1.Color = app.RED_COLOR;
            app.time = (0 : 1 : app.sampleNum)';
            app.egram_array = zeros(length(app.time), 2);
            app.atr_array = zeros(length(app.time), 1);
            app.vent_array = zeros(length(app.time), 1);
            app.UIAxes.XLim = [0 app.sampleNum];
            app.UIAxes.YLim = [-0.5 5];


            %Timer that updates egram every so often (depends on time_step)
            app.t = timer;
            app.t.TimerFcn = @(~, ~)app.UpdateEgram;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = app.time_step;
            app.t.BusyMode = 'drop';
            
            %Configure serial drop down
            delete(instrfind);
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            filename = strcat(userpath,'\userandPass.MAT');
            
            if(exist(filename,'file'))
               m = load(filename);
               users = m.users;
               passwords = m.passwords;
               loggedIn = false;
               for i = 1:length(users)
                    if(strcmp(users(i),app.username.Value))
                        if((strcmp(passwords(i),app.password.Value)))
                            loggedIn = true;
                            app.username.Value = '';
                            app.password.Value = '';
                            paramsFile = strcat(userpath,'\params.MAT');
                            if(exist(paramsFile,'file'))
                                p = load(paramsFile);
                                app.DropDown.Value = num2str(p.pacingMode);
                                app.avDelay.Value = num2str(p.AvDelay);
                                app.upperRateLimit.Value = num2str(p.uprL);
                                app.lowrateLimit.Value = num2str(p.lwrL);
                                app.VTRPaceAmplitude.Value = num2str(p.VTRAmpl);
                                app.ATRPaceAmplitude.Value = num2str(p.ATRAmpl);
                                app.VentricularPaceWidth.Value = num2str(p.pwVTR);
                                app.refVent.Value = num2str(p.refVTR);
                                app.refAtr.Value = num2str(p.refATR);
                                app.paceWidthATR.Value = num2str(p.pwATR);
                            else
                                app.avDelay.Value = '250';
                                app.upperRateLimit.Value = '120';
                                app.lowrateLimit.Value = '60';
                                app.VTRPaceAmplitude.Value = '4500';
                                app.ATRPaceAmplitude.Value = '4500';
                                app.VentricularPaceWidth.Value = '2';
                                app.refVent.Value = '400';
                                app.refAtr.Value = '400';
                                app.paceWidthATR.Value = '2';
                                app.DropDown.Value = 'VOO';
                            end
                            app.login.Visible = 'off';
                            app.control_Panel.Visible = 'on';
                        end
                    end
               end
               if(~loggedIn)
                   msgbox('Username or password incorrect.')
                   app.username.Value = '';
                   app.password.Value = '';
                   
               end
            else
                msgbox('Username or password incorrect.')
                app.username.Value = '';
                app.password.Value = '';
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.create_User.Visible ='off'; 
            app.login.Visible = 'on';
            app.newUsername.Value = '';
            app.newPassword.Value = '';
            app.confirmNewPassword.Value = '';
        end

        % Button pushed function: NewUserButton
        function NewUserButtonPushed(app, event)
            app.create_User.Visible ='on'; 
            app.login.Visible = 'off';
            app.username.Value = '';
            app.password.Value = '';
        end

        % Button pushed function: CreateUserButton
        function CreateUserButtonPushed(app, event)
            app.newUsername.Value;
            passEqual = strcmp(app.newPassword.Value,app.confirmNewPassword.Value);
            
            if (~passEqual)
                msgbox('Passwords do not match. Please re-enter your password.')
                app.newUsername.Value = '';
                app.newPassword.Value = '';
                app.confirmNewPassword.Value = '';
            else if(isempty(app.newPassword.Value) || isempty(app.newUsername.Value))
                msgbox('Password and username fields must be filled.')
                app.newUsername.Value = '';
                app.newPassword.Value = '';
                app.confirmNewPassword.Value = '';
            else
                filename = strcat(userpath,'\userandPass.MAT');
                
                if(exist(filename,'file'))
                    m = load(filename);
                    users = m.users;
                    passwords = m.passwords;
                    unique = true;
                    
                    for i = 1:length(users)
                        if(strcmp(users(i),app.newUsername.Value))
                            msgbox('Username is not unique. Please pick a new username.')
                            app.newUsername.Value = '';
                            app.newPassword.Value = '';
                            app.confirmNewPassword.Value = '';
                            unique = false;
                        end
                    end
                    
                    if(unique)
                        if(length(users) < 10)
                           users{length(users) + 1} = app.newUsername.Value;
                           passwords{length(passwords) + 1} = app.newPassword.Value;
                           save(filename, 'users', 'passwords');
                           app.newUsername.Value = '';
                           app.newPassword.Value = '';
                           app.confirmNewPassword.Value = '';
                           app.create_User.Visible ='off'; 
                           app.login.Visible = 'on';
                        else
                            msgbox('Maximum number of users registered.')
                            app.newUsername.Value = '';
                            app.newPassword.Value = '';
                            app.confirmNewPassword.Value = '';
                        end
                    end
                else
                    users = {app.newUsername.Value};
                    passwords = {app.newPassword.Value};
                    save(filename, 'users', 'passwords');
                    app.newUsername.Value = '';
                    app.newPassword.Value = '';
                    app.confirmNewPassword.Value = '';
                    app.create_User.Visible ='off'; 
                    app.login.Visible = 'on';
                end 
            end
            end
        end

        % Button pushed function: logout
        function logoutButtonPushed(app, event)
            app.login.Visible = 'on';
            app.control_Panel.Visible = 'off';
        end

        % Callback function
        function ControlPanelButtonPushed(app, event)
            app.control_Panel.Visible = 'on';
        end

        % Callback function
        function logout_2ButtonPushed(app, event)
            app.login.Visible ='on';
            app.control_Panel.Visible = 'off';
        end

        % Button pushed function: SaveTransmitButton
        function SaveTransmitButtonPushed(app, event)
            filename = strcat(userpath,'\params.MAT');
            
            pacingMode = app.DropDown.Value;
            AvDelay = str2num(app.avDelay.Value);
            uprL = str2num(app.upperRateLimit.Value);
            lwrL = str2num(app.lowrateLimit.Value);
            VTRAmpl = str2num(app.VTRPaceAmplitude.Value);
            ATRAmpl = str2num(app.ATRPaceAmplitude.Value);
            pwVTR = str2num(app.VentricularPaceWidth.Value);
            pwATR = str2num(app.paceWidthATR.Value);
            refVTR = str2num(app.refVent.Value);
            refATR = str2num(app.refAtr.Value);
            
            if ((AvDelay < 70) || (AvDelay > 300))
                msgbox('AV Delay parameter out of acceptable range. Please enter a value between 70 and 300.')
            elseif ((uprL < 60) || (uprL > 175))
                msgbox('Upper Rate Limit parameter out of acceptable range. Please enter a value between 30 and 175.')
            elseif ((lwrL < 60) || (lwrL > 175))
                msgbox('Lower Rate Limit parameter out of acceptable range. Please enter a value between 30 and 175.')
            elseif ((VTRAmpl < 500) || (VTRAmpl > 5000))
                msgbox('Ventricular Pace Amplitude parameter out of acceptable range. Please enter a value between 500 and 5000.')
            elseif ((ATRAmpl < 500) || (ATRAmpl > 5000))
                msgbox('Atrium Pace Amplitude parameter out of acceptable range. Please enter a value between 500 and 5000.')
            elseif ((pwVTR <= 0) || (pwVTR > 50))
                msgbox('Ventricular Pace Width parameter out of acceptable range. Please enter a value between 0 and 2.')
            elseif ((pwATR <= 0) || (pwATR > 50))
                msgbox('Atrium Pace Width parameter out of acceptable range. Please enter a value between 0 and 2.')
            elseif ((refVTR < 150) || (refVTR > 500))
                msgbox('Ventricular Reference parameter out of acceptable range. Please enter a value between 150 and 500.')
            elseif ((refATR < 150) || (refATR > 500))
                msgbox('Atrium Reference parameter out of acceptable range. Please enter a value between 150 and 500.')
            else   
                save(filename, 'pacingMode', 'AvDelay', 'uprL', 'lwrL', 'VTRAmpl', 'ATRAmpl', 'pwVTR', 'pwATR', 'refVTR', 'refATR');
                if (strcmp(pacingMode, 'IDLE'))
                    pace = 0;
                elseif (strcmp(pacingMode, 'VOO'))
                    pace = 1;
                elseif (strcmp(pacingMode, 'AOO'))
                    pace = 2;
                elseif (strcmp(pacingMode, 'VVI'))
                    pace = 3;
                elseif (strcmp(pacingMode, 'AAI'))
                    pace = 4;
                elseif (strcmp(pacingMode, 'VOOR'))
                    pace = 5;
                elseif (strcmp(pacingMode, 'AOOR'))
                    pace = 6;
                elseif (strcmp(pacingMode, 'VVIR'))
                    pace = 7;
                elseif (strcmp(pacingMode, 'AAIR'))
                    pace = 8;
                elseif (strcmp(pacingMode, 'DOO'))
                    pace = 9;
                elseif (strcmp(pacingMode, 'DOOR'))
                    pace = 10;
                elseif (strcmp(pacingMode, 'DDD'))
                    pace = 11;
                elseif (strcmp(pacingMode, 'DDDR'))
                    pace = 12;
                end
                
                try
                    A = [pace, uprL, lwrL, (AvDelay/10), (VTRAmpl/100), (ATRAmpl/100), pwVTR, pwATR, (refVTR/10), (refATR/10)];
                    B = uint8(A);
                    fwrite(app.s,B);
                catch e
                    fprintf(1,'The identifier was:\n%s',e.identifier);
                    fprintf(1,'There was an error! The message was:\n%s',e.message);
                end
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            delete(instrfind);
            if (~isempty(app.PortList.Value))
                %Dont try to open a com port if there are non available
                app.L1.Color = app.GREEN_COLOR;
                app.s = serial(app.PortList.Value, 'Baudrate', app.baud, 'InputBufferSize', app.bufferSize);
                fopen(app.s);
                app.com_open = true;
            else
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
            
            
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if (app.com_open) 
                stop(app.t);
                fclose(app.s);
                app.com_open = false;
            end
            app.L1.Color = app.RED_COLOR;
        end

        % Value changed function: EnableElectorgramButton
        function EnableElectorgramButtonValueChanged(app, event)
            value = app.EnableElectorgramButton.Value;
            if (value == 1)
                app.EnableElectorgramButton.Text = 'Stop Electrogram';
                app.SampleTime.Enable = 'off';
                app.NumSamplesEditField.Enable = 'off';
                app.egram_array = zeros(length(app.time), 2);
                app.atr_array = zeros(length(app.time), 1);
                app.vent_array = zeros(length(app.time), 1);
                start(app.t);
                
            else
                app.EnableElectorgramButton.Text = 'Enable Electrogram';
                app.SampleTime.Enable = 'on';
                app.NumSamplesEditField.Enable = 'on';
                stop(app.t);
            end
        end

        % Value changed function: SampleTime
        function SampleTimeValueChanged(app, event)
            value = app.SampleTime.Value;
            app.time_step = str2num(app.SampleTime.Value);
            %Update axis for change in sample rate
            app.t = timer;
            app.t.TimerFcn = @(~, ~)app.UpdateEgram;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = app.time_step;
            app.t.BusyMode = 'drop';
            
            app.time = (0 : 1 : app.sampleNum)';
        end

        % Value changed function: NumSamplesEditField
        function NumSamplesEditFieldValueChanged(app, event)
            app.sampleNum = app.NumSamplesEditField.Value;
            app.time = (0 : 1 : app.sampleNum)';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PacemakerControllerUIFigure = uifigure;
            app.PacemakerControllerUIFigure.Name = 'Pacemaker Controller';
            app.create_User = uipanel(app.PacemakerControllerUIFigure);
            app.create_User.Visible = 'off';
            app.BackButton = uibutton(app.create_User, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.CreateUserButton = uibutton(app.create_User, 'push');
            app.CreateUserButton.ButtonPushedFcn = createCallbackFcn(app, @CreateUserButtonPushed, true);
            app.CreateUserButton.Text = 'Create User';
            app.newUsername = uieditfield(app.create_User, 'text');
            app.UsernameEditField_2Label_2 = uilabel(app.create_User);
            app.UsernameEditField_2Label_2.Text = 'Username';
            app.newPassword = uieditfield(app.create_User, 'text');
            app.PasswordEditFieldLabel = uilabel(app.create_User);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.confirmNewPassword = uieditfield(app.create_User, 'text');
            app.ConfirmPasswordEditFieldLabel = uilabel(app.create_User);
            app.ConfirmPasswordEditFieldLabel.Text = 'Confirm Password';
            app.createUserTitle = uilabel(app.create_User);
            app.createUserTitle.Text = 'PACEMAKER';
            app.login = uipanel(app.PacemakerControllerUIFigure);
            app.loginTitle = uilabel(app.login);
            app.loginTitle.Text = 'PACEMAKER';
            app.UsernameEditField_2Label = uilabel(app.login);
            app.UsernameEditField_2Label.Text = 'Username';
            app.username = uieditfield(app.login, 'text');
            app.PasswordEditField_2Label = uilabel(app.login);
            app.PasswordEditField_2Label.Text = 'Password';
            app.password = uieditfield(app.login, 'text');
            app.NewUserButton = uibutton(app.login, 'push');
            app.NewUserButton.ButtonPushedFcn = createCallbackFcn(app, @NewUserButtonPushed, true);
            app.NewUserButton.Text = 'New User';
            app.LoginButton = uibutton(app.login, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.control_Panel = uipanel(app.PacemakerControllerUIFigure);
            app.control_Panel.Visible = 'off';
            app.logout = uibutton(app.control_Panel, 'push');
            app.logout.ButtonPushedFcn = createCallbackFcn(app, @logoutButtonPushed, true);
            app.logout.Text = 'Logout';
            app.controlPanelTitle = uilabel(app.control_Panel);
            app.controlPanelTitle.Text = 'CONTROL PANEL';
            app.SerialPanel = uipanel(app.control_Panel);
            app.SerialPanel.Title = 'Serial';
            app.PortsDropDownLabel = uilabel(app.SerialPanel);
            app.PortsDropDownLabel.Text = 'Ports';
            app.PortList = uidropdown(app.SerialPanel);
            app.ConnectButton = uibutton(app.SerialPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.SerialPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.SaveTransmitButton = uibutton(app.SerialPanel, 'push');
            app.SaveTransmitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTransmitButtonPushed, true);
            app.SaveTransmitButton.Text = 'Save & Transmit';
            app.L1 = uilamp(app.SerialPanel);
            app.UIAxes = uiaxes(app.control_Panel);
            title(app.UIAxes, 'Electrogram')
            xlabel(app.UIAxes, 'Sample Number')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [-0.5 5];
            app.ParametersPanel = uipanel(app.control_Panel);
            app.ParametersPanel.Title = 'Parameters';
            app.AvDelayLabel = uilabel(app.ParametersPanel);
            app.AvDelayLabel.Text = 'Av Delay:';
            app.PacingModeLabel = uilabel(app.ParametersPanel);
            app.PacingModeLabel.Text = 'Pacing Mode:';
            app.UpperRateLimitLabel = uilabel(app.ParametersPanel);
            app.UpperRateLimitLabel.Text = 'Upper Rate Limit:';
            app.LowRateLimitLabel = uilabel(app.ParametersPanel);
            app.LowRateLimitLabel.Text = 'Low Rate Limit:';
            app.VentricularPaceAmplitudeLabel = uilabel(app.ParametersPanel);
            app.VentricularPaceAmplitudeLabel.Text = 'Ventricular Pace Amplitude:';
            app.VentricularPaceWidthLabel = uilabel(app.ParametersPanel);
            app.VentricularPaceWidthLabel.Text = 'Ventricular Pace Width:';
            app.PaceWidthATRLabel = uilabel(app.ParametersPanel);
            app.PaceWidthATRLabel.Text = 'Pace Width ATR:';
            app.pacingModes = uieditfield(app.ParametersPanel, 'text');
            app.avDelay = uieditfield(app.ParametersPanel, 'text');
            app.avDelay.Value = '250';
            app.lowrateLimit = uieditfield(app.ParametersPanel, 'text');
            app.lowrateLimit.Value = '60';
            app.VTRPaceAmplitude = uieditfield(app.ParametersPanel, 'text');
            app.VTRPaceAmplitude.Value = '4500';
            app.VentricularPaceWidth = uieditfield(app.ParametersPanel, 'text');
            app.VentricularPaceWidth.Value = '2';
            app.paceWidthATR = uieditfield(app.ParametersPanel, 'text');
            app.paceWidthATR.Value = '2';
            app.BPMLabel = uilabel(app.ParametersPanel);
            app.BPMLabel.Text = 'BPM';
            app.BPMLabel_2 = uilabel(app.ParametersPanel);
            app.BPMLabel_2.Text = 'BPM';
            app.mSecLabel_3 = uilabel(app.ParametersPanel);
            app.mSecLabel_3.Text = 'mSec.';
            app.mSecLabel_4 = uilabel(app.ParametersPanel);
            app.mSecLabel_4.Text = 'mSec.';
            app.mVLabel = uilabel(app.ParametersPanel);
            app.mVLabel.Text = 'mV';
            app.ATRPaceAmplitudeLabel = uilabel(app.ParametersPanel);
            app.ATRPaceAmplitudeLabel.Text = 'ATR Pace Amplitude: ';
            app.ATRPaceAmplitude = uieditfield(app.ParametersPanel, 'text');
            app.ATRPaceAmplitude.Value = '4500';
            app.upperRateLimit = uieditfield(app.ParametersPanel, 'text');
            app.upperRateLimit.Value = '120';
            app.DropDown = uidropdown(app.ParametersPanel);
            app.DropDown.Items = {'IDLE', 'VOO', 'AOO', 'VVI', 'AAI', 'VOOR', 'AOOR', 'VVIR', 'AAIR', 'DOO', 'DOOR', 'DDD', 'DDDR'};
            app.DropDown.Value = 'VOO';
            app.RefPeriodforVentricleLabel = uilabel(app.ParametersPanel);
            app.RefPeriodforVentricleLabel.Text = 'Ref. Period for Ventricle: ';
            app.RefPeriodforATRLabel = uilabel(app.ParametersPanel);
            app.RefPeriodforATRLabel.Text = 'Ref. Period for ATR:';
            app.refVent = uieditfield(app.ParametersPanel, 'text');
            app.refVent.Value = '400';
            app.refAtr = uieditfield(app.ParametersPanel, 'text');
            app.refAtr.Value = '400';
            app.mSecLabel_5 = uilabel(app.ParametersPanel);
            app.mSecLabel_5.Text = 'mSec.';
            app.mVLabel_2 = uilabel(app.ParametersPanel);
            app.mVLabel_2.Text = 'mV';
            app.mSecLabel_6 = uilabel(app.ParametersPanel);
            app.mSecLabel_6.Text = 'mSec.';
            app.mSecLabel_7 = uilabel(app.ParametersPanel);
            app.mSecLabel_7.Text = 'mSec.';
            app.ElectrogramSettingsPanel = uipanel(app.control_Panel);
            app.ElectrogramSettingsPanel.Title = 'Electrogram Settings';
            app.VentricleCheckBox = uicheckbox(app.ElectrogramSettingsPanel);
            app.VentricleCheckBox.Text = 'Ventricle';
            app.VentricleCheckBox.Value = true;
            app.AtriumCheckBox = uicheckbox(app.ElectrogramSettingsPanel);
            app.AtriumCheckBox.Text = 'Atrium';
            app.AtriumCheckBox.Value = true;
            app.SampleTime = uidropdown(app.ElectrogramSettingsPanel);
            app.SampleTime.Items = {'0.1', '0.01', '0.001'};
            app.SampleTime.ValueChangedFcn = createCallbackFcn(app, @SampleTimeValueChanged, true);
            app.SampleTime.Value = '0.1';
            app.SampleTimeDropDownLabel = uilabel(app.ElectrogramSettingsPanel);
            app.SampleTimeDropDownLabel.Text = 'Sample Time';
            app.NumSamplesEditFieldLabel = uilabel(app.ElectrogramSettingsPanel);
            app.NumSamplesEditFieldLabel.Text = 'Num Samples';
            app.NumSamplesEditField = uieditfield(app.ElectrogramSettingsPanel, 'numeric');
            app.NumSamplesEditField.Limits = [1 1000];
            app.NumSamplesEditField.ValueDisplayFormat = '%.0f';
            app.NumSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @NumSamplesEditFieldValueChanged, true);
            app.NumSamplesEditField.Value = 100;
            app.EnableElectorgramButton = uibutton(app.ElectrogramSettingsPanel, 'state');
            app.EnableElectorgramButton.ValueChangedFcn = createCallbackFcn(app, @EnableElectorgramButtonValueChanged, true);
            app.EnableElectorgramButton.Text = 'Enable Electorgram';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Assingment3_3K04_DCM_Group_7

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PacemakerControllerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PacemakerControllerUIFigure)
        end
    end
end